classdef OPEAlgorithm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OPEAlgorithmUIFigure            matlab.ui.Figure
        SelectClusteringAlgorithmLabel  matlab.ui.control.Label
        SelectClusteringAlgorithmDropDown  matlab.ui.control.DropDown
        TextArea                        matlab.ui.control.TextArea
        StartOPEAlgorithmButton         matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        TabGroup                        matlab.ui.container.TabGroup
        DataTab_3                       matlab.ui.container.Tab
        LoadDataButton                  matlab.ui.control.Button
        PrincipalFeaturesSpinner_3Label  matlab.ui.control.Label
        PrincipalFeaturesSpinner_3      matlab.ui.control.Spinner
        FeatureExtractionTechniqueDropDownLabel  matlab.ui.control.Label
        FeatureExtractionTechniqueDropDown  matlab.ui.control.DropDown
        SpikesTab                       matlab.ui.container.Tab
        LoadSpikesButton                matlab.ui.control.Button
        LoadGroundTruthButton           matlab.ui.control.Button
        FeatureExtractionTechniqueDropDown_2Label  matlab.ui.control.Label
        FeatureExtractionTechniqueDropDown_2  matlab.ui.control.DropDown
        PrincipalFeaturesSpinner_2Label  matlab.ui.control.Label
        PrincipalFeaturesSpinner_2      matlab.ui.control.Spinner
        FeaturesTab                     matlab.ui.container.Tab
        LoadFeaturesButton              matlab.ui.control.Button
        LoadGroundTruthButton_2         matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end


    methods (Access = private)

        % Button pushed function: LoadSpikesButton
        function LoadSpikesButtonPushed(app, event)
          [file,path] = uigetfile;
          filepath=[path file];
          global spikes; 
%           spikes=open(filepath)
      spikes=cell2mat(struct2cell(open(filepath)));
         app.LoadSpikesButton.Text = file;
        end

        % Button pushed function: LoadGroundTruthButton
        function LoadGroundTruthButtonPushed(app, event)
          [file,path] = uigetfile;
          filepath=[path file];
          global Z;
          Z=cell2mat(struct2cell(open(filepath)));
          
               app.LoadGroundTruthButton.Text = file;
        end

        % Button pushed function: StartOPEAlgorithmButton
        function StartOPEAlgorithmButtonPushed(app, event)


global Z;
global X;
Z
X
k=0;
Y=[];
ConA=[];
AccA=[];
c=1;
z=Z(1:1000);
x=X((1:1000),:);
l=length(x);
mat=zeros(l,l);
for i=1:l
for j=1:l
  mat(i,j)= sqrt(sum((x(i,:)-x(j,:)).^2));
end
end
mn=min(min(mat));
mx=max(max(mat));
t=mean(mean(mat));
un=0;
ut=mx;
p=1;
up=t;
ul=t;
tem1=ut
tem2=un
conv=[0 0 0];
conv(2)=t;
CL=[];
logh=0;
logval=app.SelectClusteringAlgorithmDropDown.Value;
       
tic
for r=1:80
    if ut==un
        k=r;
        break;
    end
conv(1)= mean(mat(find(mat(:)<ul&mat(:)>un)));
conv(3)= mean(mat(find(mat(:)>up&mat(:)<ut)));
switch logval
     case 'MeanShift'
        for i=1:3
        [clustCent,point2cluster,clustMembsCell] = MeanShiftCluster(x',conv(i),'false');
        [acc,conf]=masoodaccuracy(Z,point2cluster);
        CL=[CL;point2cluster]
        A(i)= acc;
        tit='MeanShift Clustering'
        xax='Bandwidth'
        end
    otherwise
        for i=1:3
        [C, ptsC, centres] = dbscan(x',conv(i),0);
        [acc,conf]=masoodaccuracy(Z,ptsC');
        A(i)= acc;
        CL=[CL;ptsC]
        tit='DBSCAN Clustering'
        xax='Epsilon'
        end
end
AccA=[AccA A]
ConA=[ConA conv]
elp(r)=max(A);
if A(1)>60|A(2)>60|A(3)>60
I =A(1)+(A(1)*0.02);
D =A(1)-(A(1)*0.02);
else
I =A(1)+(A(1)*0.12);
D =A(1)-(A(1)*0.12);
end
    A
conv
if A(1)<I & A(2)<I & A(3)<I & A(1)>D & A(2)>D & A(3)>D 
    if A(1)>60|A(2)>60|A(3)>60
    if c==1
    B=A;
    c=2
    else
        k=r;
        break;
    end
    end
    ul=conv(1)
    up=conv(3)
    un
    ut
    conv(2)
    tem1=conv(1)
    tem2=conv(3)
    elseif A(1)>(A(2)+(A(2)*0.01))& A(1)>(A(3)+(A(3)*0.01)) 
    ul=conv(1)
    up=conv(1)
    conv(2)=conv(1);
    conv(2)
    ut=tem1
    un
    tem1=conv(1)
    tem2=ut
elseif A(2)>(A(1)+(A(1)*0.01))& A(2)>(A(3)+(A(3)*0.01)) 
    ul=conv(2)
    up=conv(2)
    ut=conv(3)
    un=conv(1)
    conv(2)
    tem1=conv(1)
    tem2=conv(3)
elseif A(3)>(A(1)+(A(1)*0.01))& A(3)>(A(2)+(A(2)*0.01))
   ul=conv(3)
   up=conv(3)
   ut
   un=conv(2)
   conv(2)=conv(3);
   tem1=un 
   tem2=ut   
else
    if p==1
    B=A;
    p=2
        ul=conv(1)
    up=conv(3)
    un
    ut
    conv(2)
    tem1=conv(1)
    tem2=conv(3)
    else
  k=r
    break;
    end
end
 
end
 atime=toc
t =conv(find(A==max(A)));
Optimised_Bandwidth_is=t(1)
Number_of_Iterations=k
[B I]=sort(ConA,'ascend');
T=AccA(I);
ax1=app.UIAxes2
hold(ax1,'on')
ax1.XLabel.String=xax
line(ax1,B,T,'Color','b','Linewidth',2,'Marker','o')
legend(ax1,{'Algorithm Accuracy'})
hold(ax1,'off')
pause(0.5);
Algorithm_Time=atime
Algorithm_Iterations=Number_of_Iterations
Algorithm_Max_Accuracy=max(AccA)
Optimised_Bandwidth_Algo_is=Optimised_Bandwidth_is
clust =CL((find(AccA==max(AccA))),:);
idx=clust(1,:);
t=max(idx);
gx=app.UIAxes
hold (gx,'on')
gx.Title.String= tit
for i=t:-1:0
%  plot(X(idx==i,1),X(idx==i,2),'MarkerSize',8)
scatter(gx,X(idx==i,1),X(idx==i,2),12,'filled');
end
legend(gx,{'Cluster 1','Cluster 2','Cluster 3'})
hold(gx,'off')
pause(0.5);
tab=app.UITable
d = {'Maximum Accuracy =',Algorithm_Max_Accuracy;'Computational Time',Algorithm_Time;'Number of Iterations',Algorithm_Iterations;'Optimised Parameter',Optimised_Bandwidth_Algo_is};
tab.Data = d;   
  
        end

        % Value changed function: FeatureExtractionTechniqueDropDown
        function FeatureExtractionTechniqueDropDownValueChanged(app, event)
           global spikes;
           global X;
            feat = app.FeatureExtractionTechniqueDropDown.Value;
            inputs=app.PrincipalFeaturesSpinner_3.Value;
            switch feat
                case 'Wavelets'
                scales = 4;
inputs 
nspk = size(spikes,1);
ls = size(spikes,2);

if inputs>ls
	error('number of features exceeds spikes length');
end

cc=zeros(nspk,ls);
%   if exist('wavedec')                             % Looks for Wavelets Toolbox
%             for i=1:nspk                                % Wavelet decomposition
%                 [c,l]=wavedec(spikes(i,:),scales,'haar');
%                 cc(i,1:ls)=c(1:ls);
%             end
%         else
            for i=1:nspk                                % Replaces Wavelets Toolbox, if not available
                [c,l]=fix_wavedec(spikes(i,:),scales);
                cc(i,1:ls)=c(1:ls);
            end
%         end
        for i=1:ls                                  % KS test for coefficient selection   
            thr_dist = std(cc(:,i)) * 3;
            thr_dist_min = mean(cc(:,i)) - thr_dist;
            thr_dist_max = mean(cc(:,i)) + thr_dist;
            aux = cc(find(cc(:,i)>thr_dist_min & cc(:,i)<thr_dist_max),i);
            
            if length(aux) > 10;
                [ksstat]=test_ks(aux);
                sd(i)=ksstat;
            else
                sd(i)=0;
            end
        end
        [max_sd ind]=sort(sd);
        coeff(1:inputs)=ind(ls:-1:ls-inputs+1);
        
        inspk=zeros(nspk,inputs);
for i=1:nspk
    for j=1:inputs
        inspk(i,j)=cc(i,coeff(j));
    end
end

X=inspk
                otherwise 'PCA'
                    X = pca(spikes','NumComponents',inputs)
            end
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
        [file,path] = uigetfile;
        filepath=[path file];
        app.LoadDataButton.Text=file;
        S=open(filepath);
       global spikes Z;
        Zt=cell2mat(S.spike_times)';
        Z= cell2mat(S.spike_class(1,1))';
        Xd=S.data;
        l=length(Zt);
        Si=zeros(l,64);
        size(Si);
        for i=1:l
            kt=Zt(i,1);
            det=(kt-19):(kt+44);
            Si(i,:)=Xd(1,det);
        end
        
        spikes= Si;
        
        end

        % Button pushed function: LoadFeaturesButton
        function LoadFeaturesButtonPushed(app, event)
            [file,path] = uigetfile;
          filepath=[path file];
          global X;
          X=cell2mat(struct2cell(open(filepath)));
          
               app.LoadFeaturesButton.Text = file;
        end

        % Button pushed function: LoadGroundTruthButton_2
        function LoadGroundTruthButton_2Pushed(app, event)
          [file,path] = uigetfile;
          filepath=[path file];
          global Z;
          Z=cell2mat(struct2cell(open(filepath)));
          app.LoadGroundTruthButton_2.Text = file;
        end

        % Value changed function: FeatureExtractionTechniqueDropDown_2
        function FeatureExtractionTechniqueDropDown_2ValueChanged(app, event)
            feat = app.FeatureExtractionTechniqueDropDown_2.Value;
                   global spikes;
           global X;
                        inputs=app.PrincipalFeaturesSpinner_2.Value;
            switch feat
                case 'Wavelets'
                scales = 4;
inputs 
nspk = size(spikes,1);
ls = size(spikes,2);

if inputs>ls
	error('number of features exceeds spikes length');
end

cc=zeros(nspk,ls);
  if exist('wavedec')                             % Looks for Wavelets Toolbox
            for i=1:nspk                                % Wavelet decomposition
                [c,l]=wavedec(spikes(i,:),scales,'haar');
                cc(i,1:ls)=c(1:ls);
            end
        else
            for i=1:nspk                                % Replaces Wavelets Toolbox, if not available
                [c,l]=fix_wavedec(spikes(i,:),scales);
                cc(i,1:ls)=c(1:ls);
            end
        end
        for i=1:ls                                  % KS test for coefficient selection   
            thr_dist = std(cc(:,i)) * 3;
            thr_dist_min = mean(cc(:,i)) - thr_dist;
            thr_dist_max = mean(cc(:,i)) + thr_dist;
            aux = cc(find(cc(:,i)>thr_dist_min & cc(:,i)<thr_dist_max),i);
            
            if length(aux) > 10;
                [ksstat]=test_ks(aux);
                sd(i)=ksstat;
            else
                sd(i)=0;
            end
        end
        [max_sd ind]=sort(sd);
        coeff(1:inputs)=ind(ls:-1:ls-inputs+1);
        
        inspk=zeros(nspk,inputs);
for i=1:nspk
    for j=1:inputs
        inspk(i,j)=cc(i,coeff(j));
    end
end

X=inspk
                otherwise 'PCA'
                    

                    X = pca(spikes','NumComponents',inputs)
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OPEAlgorithmUIFigure = uifigure;
            app.OPEAlgorithmUIFigure.Name = 'OPE Algorithm';
            app.SelectClusteringAlgorithmLabel = uilabel(app.OPEAlgorithmUIFigure);
            app.SelectClusteringAlgorithmLabel.Text = 'Select Clustering Algorithm';
            app.SelectClusteringAlgorithmDropDown = uidropdown(app.OPEAlgorithmUIFigure);
            app.SelectClusteringAlgorithmDropDown.Items = {'MeanShift', 'DBSCAN'};
            app.SelectClusteringAlgorithmDropDown.Value = 'MeanShift';
            app.TextArea = uitextarea(app.OPEAlgorithmUIFigure);
            app.TextArea.Value = {'OPE Algorithm'};
            app.StartOPEAlgorithmButton = uibutton(app.OPEAlgorithmUIFigure, 'push');
            app.StartOPEAlgorithmButton.ButtonPushedFcn = createCallbackFcn(app, @StartOPEAlgorithmButtonPushed, true);
            app.StartOPEAlgorithmButton.Text = {'Start '; 'OPE Algorithm'};
            app.UITable = uitable(app.OPEAlgorithmUIFigure);
            app.UITable.ColumnName = {'Properties'; 'Results'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true false];
            app.TabGroup = uitabgroup(app.OPEAlgorithmUIFigure);
            app.DataTab_3 = uitab(app.TabGroup);
            app.DataTab_3.Title = 'Data';
            app.LoadDataButton = uibutton(app.DataTab_3, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.PrincipalFeaturesSpinner_3Label = uilabel(app.DataTab_3);
            app.PrincipalFeaturesSpinner_3Label.Text = 'Principal Features';
            app.PrincipalFeaturesSpinner_3 = uispinner(app.DataTab_3);
            app.PrincipalFeaturesSpinner_3.Limits = [0 100];
            app.PrincipalFeaturesSpinner_3.Value = 10;
            app.FeatureExtractionTechniqueDropDownLabel = uilabel(app.DataTab_3);
            app.FeatureExtractionTechniqueDropDownLabel.Text = 'Feature Extraction Technique';
            app.FeatureExtractionTechniqueDropDown = uidropdown(app.DataTab_3);
            app.FeatureExtractionTechniqueDropDown.Items = {'PCA', 'Wavelets', 'Select One'};
            app.FeatureExtractionTechniqueDropDown.ValueChangedFcn = createCallbackFcn(app, @FeatureExtractionTechniqueDropDownValueChanged, true);
            app.FeatureExtractionTechniqueDropDown.Value = 'Select One';
            app.SpikesTab = uitab(app.TabGroup);
            app.SpikesTab.Title = 'Spikes';
            app.LoadSpikesButton = uibutton(app.SpikesTab, 'push');
            app.LoadSpikesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSpikesButtonPushed, true);
            app.LoadSpikesButton.Text = 'Load Spikes';
            app.LoadGroundTruthButton = uibutton(app.SpikesTab, 'push');
            app.LoadGroundTruthButton.ButtonPushedFcn = createCallbackFcn(app, @LoadGroundTruthButtonPushed, true);
            app.LoadGroundTruthButton.Text = 'Load Ground Truth';
            app.FeatureExtractionTechniqueDropDown_2Label = uilabel(app.SpikesTab);
            app.FeatureExtractionTechniqueDropDown_2Label.Text = 'Feature Extraction Technique';
            app.FeatureExtractionTechniqueDropDown_2 = uidropdown(app.SpikesTab);
            app.FeatureExtractionTechniqueDropDown_2.Items = {'PCA', 'Wavelets', 'Select One'};
            app.FeatureExtractionTechniqueDropDown_2.ValueChangedFcn = createCallbackFcn(app, @FeatureExtractionTechniqueDropDown_2ValueChanged, true);
            app.FeatureExtractionTechniqueDropDown_2.Value = 'Select One';
            app.PrincipalFeaturesSpinner_2Label = uilabel(app.SpikesTab);
            app.PrincipalFeaturesSpinner_2Label.Text = 'Principal Features';
            app.PrincipalFeaturesSpinner_2 = uispinner(app.SpikesTab);
            app.PrincipalFeaturesSpinner_2.Limits = [0 100];
            app.PrincipalFeaturesSpinner_2.ValueDisplayFormat = '%.0f';
            app.PrincipalFeaturesSpinner_2.Value = 10;
            app.FeaturesTab = uitab(app.TabGroup);
            app.FeaturesTab.Title = 'Features';
            app.LoadFeaturesButton = uibutton(app.FeaturesTab, 'push');
            app.LoadFeaturesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFeaturesButtonPushed, true);
            app.LoadFeaturesButton.Text = 'Load Features';
            app.LoadGroundTruthButton_2 = uibutton(app.FeaturesTab, 'push');
            app.LoadGroundTruthButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadGroundTruthButton_2Pushed, true);
            app.LoadGroundTruthButton_2.Text = 'Load Ground Truth';
            app.UIAxes2 = uiaxes(app.OPEAlgorithmUIFigure);
            title(app.UIAxes2, 'MAP Algortihm Parameter Optimization')
            xlabel(app.UIAxes2, 'Paramter (Bandwidth/Epsilon)')
            ylabel(app.UIAxes2, 'Accuracy (%)')
            app.UIAxes = uiaxes(app.OPEAlgorithmUIFigure);
            title(app.UIAxes, 'Clustering Results')
            xlabel(app.UIAxes, 'Dim 1')
            ylabel(app.UIAxes, 'Dim 2')
        end
    end

    methods (Access = public)

        % Construct app
        function app = OPEAlgorithm

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OPEAlgorithmUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OPEAlgorithmUIFigure)
        end
    end
end