classdef largeDatahandle < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ClusteringUIFigure              matlab.ui.Figure
        SelectClusteringAlgorithmLabel  matlab.ui.control.Label
        SelectClusteringAlgorithmDropDown  matlab.ui.control.DropDown
        TextArea                        matlab.ui.control.TextArea
        StartClusteringButton           matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        DataTab_3                       matlab.ui.container.Tab
        LoadDataButton                  matlab.ui.control.Button
        PrincipalFeaturesSpinner_3Label  matlab.ui.control.Label
        PrincipalFeaturesSpinner_3      matlab.ui.control.Spinner
        FeatureExtractionTechniqueDropDownLabel  matlab.ui.control.Label
        FeatureExtractionTechniqueDropDown  matlab.ui.control.DropDown
        SpikesTab                       matlab.ui.container.Tab
        LoadSpikesButton                matlab.ui.control.Button
        LoadGroundTruthButton           matlab.ui.control.Button
        FeatureExtractionTechniqueDropDown_2Label  matlab.ui.control.Label
        FeatureExtractionTechniqueDropDown_2  matlab.ui.control.DropDown
        PrincipalFeaturesSpinner_2Label  matlab.ui.control.Label
        PrincipalFeaturesSpinner_2      matlab.ui.control.Spinner
        FeaturesTab                     matlab.ui.container.Tab
        LoadFeaturesButton              matlab.ui.control.Button
        LoadGroundTruthButton_2         matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        SlidetogetNgroupsofdataLabel    matlab.ui.control.Label
        SlidetogetNgroupsofdataSlider   matlab.ui.control.Slider
        ClusteringAccuracyLabel         matlab.ui.control.Label
        ClusteringAccuracyGauge         matlab.ui.control.Gauge
        UIAxes3                         matlab.ui.control.UIAxes
    end


    methods (Access = private)

        % Button pushed function: LoadSpikesButton
        function LoadSpikesButtonPushed(app, event)
          [file,path] = uigetfile;
          filepath=[path file];
          global spikes; 
%           spikes=open(filepath)
      spikes=cell2mat(struct2cell(open(filepath)));
         app.LoadSpikesButton.Text = file;
        end

        % Button pushed function: LoadGroundTruthButton
        function LoadGroundTruthButtonPushed(app, event)
          [file,path] = uigetfile;
          filepath=[path file];
          global Z;
          Z=cell2mat(struct2cell(open(filepath)));
          
               app.LoadGroundTruthButton.Text = file;
        end

        % Button pushed function: StartClusteringButton
        function StartClusteringButtonPushed(app, event)


global Z;
global X;
global spikes;

Z
X


logval=app.SelectClusteringAlgorithmDropDown.Value
%----------------------
len=length(X)
n=app.SlidetogetNgroupsofdataSlider.Value;
p=max(Z);
[nums] = arraydivider (len,n)
amb=int32(len*p/100);
clust=[]
OB=[]
Ac=[]
clustarr=[];
ambpair=[];
ambs=[];
j=1;
for i=1:n
    if (i==1)
    x=X(nums(i):nums(i+1),:)
    g=Z(nums(i):nums(i+1),:)
    else
        x=X(nums(i)-amb:nums(i+1),:);
        g=Z(nums(i)-amb:nums(i+1),:);
    end
    [cl,OB(i),Ac(i),tit,xax] = opertest(x,g,logval);
%     figplotver2(x,cl')
    clustarr{i}=cl
    th=length(cl)
    bn(i)=th
    if (i==1)
        ambs{j}= cl(th-amb:th);
        j=j+1;
    elseif(i==n)
        ambs{j}= cl(1:amb+1);
    else
        ambs{j}= cl(1:amb+1);
        j=j+1;
        ambs{j}= cl(th-amb:th);
        j=j+1;
    end
end
for(i=1:n-1)
    ambpair{i}=[ambs{((i-1)*2)+1}',ambs{(i*2)}'] 
end
yn=3;
chng=[]
clustr=clustarr{n}
for  (i=n-1:-1:1)
 [u,jj,kk]=unique(ambpair{i},'rows')
 [B,I] = maxk(accumarray(kk,1),yn)
 for (h=1:yn)
 chng{h}=find(clustr==u(I(h),2))    
 end
 for (h=1:yn)
 clustr(chng{h})=u(I(h),1)
 end
 l=length(clustr)
 clustr=[clustarr{i} clustr(amb+2:l)]
%  pause
   
end
% clustr
    figplotver3(X,clustr',tit,xax)
spikesplot(spikes,clustr');
%----------------------


% ax1=app.UIAxes2
% hold(ax1,'on')
% ax1.XLabel.String=xax
% line(ax1,B,T,'Color','b','Linewidth',2,'Marker','o')
% legend(ax1,{'Algorithm Accuracy'})
% hold(ax1,'off')
% pause(0.5);
% Algorithm_Time=atime
% Algorithm_Iterations=Number_of_Iterations
% Algorithm_Max_Accuracy=max(AccA)
% Optimised_Bandwidth_Algo_is=Optimised_Bandwidth_is
% clust =CL((find(AccA==max(AccA))),:);
% idx=clust(1,:);
% t=max(idx);

% gx=app.UIAxes
% hold (gx,'on')
% 
% 
% gx.Title.String= tit
% 
% 
% 
% for i=t:-1:0
% %  plot(X(idx==i,1),X(idx==i,2),'MarkerSize',8)
% scatter(gx,X(idx==i,1),X(idx==i,2),12,'filled');
% end
% legend(gx,{'Cluster 1','Cluster 2','Cluster 3'})
% 
% 
% 
% 
% hold(gx,'off')
% pause(0.5);


% %Table dATA  Initialization
% tab=app.UITable
% d = {'Maximum Accuracy =',Algorithm_Max_Accuracy;'Computational Time',Algorithm_Time;'Number of Iterations',Algorithm_Iterations;'Optimised Parameter',Optimised_Bandwidth_Algo_is};
% tab.Data = d;   


% 
function figplotver3(X,idx,tit,xax)
t=max(idx)
range = min(idx):max(idx);
res = [range; histc(idx(:)', range)]'; % res has values in first column, counts in second.
sortedres = sortrows(res, 2)
cVec = 'brwg';
mrk='<*so'
% figure;
gx=app.UIAxes
hold (gx,'on')
gx.Title.String= tit
k=4;
for i=1:t
    if (i>t-4)                                                              
        k=t-i+1;
    else
        k=4;
    end
    plot(gx,X(idx==sortedres(i),1),X(idx==sortedres(i),2),'Color',cVec(k),'Linewidth',0.5,'LineStyle','none','Marker',mrk(k),'MarkerSize',4)
%scatter(X(idx==sortedres(i),1),X(idx==sortedres(i),2),12,'filled')
% hold on
end
% gx.xlabel('Dim 1')
% gx.ylabel('Dim 2')
% 
%title('Clustering Results at OPT Optimised Parameters')
f=get(gx,'Children');
legend(gx,[f(1),f(2),f(3),f(4)],'\color{white} Cluster 1','\color{white} Cluster 2','\color{white} Cluster 3','\color{white} Unclustered')
set(gx,'FontSize',16)
  % set(gcf, 'Position', [300, 10, 500, 500])


hold(gx,'off')
% axis(gx,[-0.03 0.05 -0.035 0.05])


end

            function  spikesplot(X,Z)
                sp=app.UIAxes2;
            plot(sp,X(Z==1),'Color','w','Linewidth',0.5)
            sp=app.UIAxes3;
            plot(sp,X(Z),'Color','r','Linewidth',1)
             end

  
        end

        % Value changed function: FeatureExtractionTechniqueDropDown
        function FeatureExtractionTechniqueDropDownValueChanged(app, event)
           global spikes;
           global X;
            feat = app.FeatureExtractionTechniqueDropDown.Value;
            inputs=app.PrincipalFeaturesSpinner_3.Value;
            switch feat
                case 'Wavelets'
                scales = 4;
inputs 
nspk = size(spikes,1);
ls = size(spikes,2);

if inputs>ls
	error('number of features exceeds spikes length');
end

cc=zeros(nspk,ls);
%   if exist('wavedec')                             % Looks for Wavelets Toolbox
%             for i=1:nspk                                % Wavelet decomposition
%                 [c,l]=wavedec(spikes(i,:),scales,'haar');
%                 cc(i,1:ls)=c(1:ls);
%             end
%         else
            for i=1:nspk                                % Replaces Wavelets Toolbox, if not available
                [c,l]=fix_wavedec(spikes(i,:),scales);
                cc(i,1:ls)=c(1:ls);
            end
%         end
        for i=1:ls                                  % KS test for coefficient selection   
            thr_dist = std(cc(:,i)) * 3;
            thr_dist_min = mean(cc(:,i)) - thr_dist;
            thr_dist_max = mean(cc(:,i)) + thr_dist;
            aux = cc(find(cc(:,i)>thr_dist_min & cc(:,i)<thr_dist_max),i);
            
            if length(aux) > 10;
                [ksstat]=test_ks(aux);
                sd(i)=ksstat;
            else
                sd(i)=0;
            end
        end
        [max_sd ind]=sort(sd);
        coeff(1:inputs)=ind(ls:-1:ls-inputs+1);
        
        inspk=zeros(nspk,inputs);
for i=1:nspk
    for j=1:inputs
        inspk(i,j)=cc(i,coeff(j));
    end
end

X=inspk
                otherwise 'PCA'
                    X = pca(spikes','NumComponents',inputs)
            end
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
        [file,path] = uigetfile;
        filepath=[path file];
        app.LoadDataButton.Text=file;
        S=open(filepath);
       global spikes Z;
        Zt=cell2mat(S.spike_times)';
        Z= cell2mat(S.spike_class(1,1))';
        Xd=S.data;
        l=length(Zt);
        Si=zeros(l,64);
        size(Si);
        for i=1:l
            kt=Zt(i,1);
            det=(kt-19):(kt+44);
            Si(i,:)=Xd(1,det);
        end
        
        spikes= Si;
        
        end

        % Button pushed function: LoadFeaturesButton
        function LoadFeaturesButtonPushed(app, event)
            [file,path] = uigetfile;
          filepath=[path file];
          global X;
          X=cell2mat(struct2cell(open(filepath)));
          
               app.LoadFeaturesButton.Text = file;
        end

        % Button pushed function: LoadGroundTruthButton_2
        function LoadGroundTruthButton_2Pushed(app, event)
          [file,path] = uigetfile;
          filepath=[path file];
          global Z;
          Z=cell2mat(struct2cell(open(filepath)));
          app.LoadGroundTruthButton_2.Text = file;
        end

        % Value changed function: FeatureExtractionTechniqueDropDown_2
        function FeatureExtractionTechniqueDropDown_2ValueChanged(app, event)
            feat = app.FeatureExtractionTechniqueDropDown_2.Value;
                   global spikes;
           global X;
                        inputs=app.PrincipalFeaturesSpinner_2.Value;
            switch feat
                case 'Wavelets'
                scales = 4;
inputs 
nspk = size(spikes,1);
ls = size(spikes,2);

if inputs>ls
	error('number of features exceeds spikes length');
end

cc=zeros(nspk,ls);
  if exist('wavedec')                             % Looks for Wavelets Toolbox
            for i=1:nspk                                % Wavelet decomposition
                [c,l]=wavedec(spikes(i,:),scales,'haar');
                cc(i,1:ls)=c(1:ls);
            end
        else
            for i=1:nspk                                % Replaces Wavelets Toolbox, if not available
                [c,l]=fix_wavedec(spikes(i,:),scales);
                cc(i,1:ls)=c(1:ls);
            end
        end
        for i=1:ls                                  % KS test for coefficient selection   
            thr_dist = std(cc(:,i)) * 3;
            thr_dist_min = mean(cc(:,i)) - thr_dist;
            thr_dist_max = mean(cc(:,i)) + thr_dist;
            aux = cc(find(cc(:,i)>thr_dist_min & cc(:,i)<thr_dist_max),i);
            
            if length(aux) > 10;
                [ksstat]=test_ks(aux);
                sd(i)=ksstat;
            else
                sd(i)=0;
            end
        end
        [max_sd ind]=sort(sd);
        coeff(1:inputs)=ind(ls:-1:ls-inputs+1);
        
        inspk=zeros(nspk,inputs);
for i=1:nspk
    for j=1:inputs
        inspk(i,j)=cc(i,coeff(j));
    end
end

X=inspk
                otherwise 'PCA'
                    

                    X = pca(spikes','NumComponents',inputs)
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ClusteringUIFigure = uifigure;
            app.ClusteringUIFigure.Name = 'Clustering';
            app.SelectClusteringAlgorithmLabel = uilabel(app.ClusteringUIFigure);
            app.SelectClusteringAlgorithmLabel.Text = 'Select Clustering Algorithm';
            app.SelectClusteringAlgorithmDropDown = uidropdown(app.ClusteringUIFigure);
            app.SelectClusteringAlgorithmDropDown.Items = {'MeanShift', 'DBSCAN'};
            app.SelectClusteringAlgorithmDropDown.Value = 'MeanShift';
            app.TextArea = uitextarea(app.ClusteringUIFigure);
            app.TextArea.Value = {'Clustering Tool Ver 1.0'};
            app.StartClusteringButton = uibutton(app.ClusteringUIFigure, 'push');
            app.StartClusteringButton.ButtonPushedFcn = createCallbackFcn(app, @StartClusteringButtonPushed, true);
            app.StartClusteringButton.Text = 'Start Clustering';
            app.TabGroup = uitabgroup(app.ClusteringUIFigure);
            app.DataTab_3 = uitab(app.TabGroup);
            app.DataTab_3.Title = 'Data';
            app.LoadDataButton = uibutton(app.DataTab_3, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.PrincipalFeaturesSpinner_3Label = uilabel(app.DataTab_3);
            app.PrincipalFeaturesSpinner_3Label.Text = 'Principal Features';
            app.PrincipalFeaturesSpinner_3 = uispinner(app.DataTab_3);
            app.PrincipalFeaturesSpinner_3.Limits = [0 100];
            app.PrincipalFeaturesSpinner_3.Value = 10;
            app.FeatureExtractionTechniqueDropDownLabel = uilabel(app.DataTab_3);
            app.FeatureExtractionTechniqueDropDownLabel.Text = 'Feature Extraction Technique';
            app.FeatureExtractionTechniqueDropDown = uidropdown(app.DataTab_3);
            app.FeatureExtractionTechniqueDropDown.Items = {'PCA', 'Wavelets', 'Select One'};
            app.FeatureExtractionTechniqueDropDown.ValueChangedFcn = createCallbackFcn(app, @FeatureExtractionTechniqueDropDownValueChanged, true);
            app.FeatureExtractionTechniqueDropDown.Value = 'Select One';
            app.SpikesTab = uitab(app.TabGroup);
            app.SpikesTab.Title = 'Spikes';
            app.LoadSpikesButton = uibutton(app.SpikesTab, 'push');
            app.LoadSpikesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSpikesButtonPushed, true);
            app.LoadSpikesButton.Text = 'Load Spikes';
            app.LoadGroundTruthButton = uibutton(app.SpikesTab, 'push');
            app.LoadGroundTruthButton.ButtonPushedFcn = createCallbackFcn(app, @LoadGroundTruthButtonPushed, true);
            app.LoadGroundTruthButton.Text = 'Load Ground Truth';
            app.FeatureExtractionTechniqueDropDown_2Label = uilabel(app.SpikesTab);
            app.FeatureExtractionTechniqueDropDown_2Label.Text = 'Feature Extraction Technique';
            app.FeatureExtractionTechniqueDropDown_2 = uidropdown(app.SpikesTab);
            app.FeatureExtractionTechniqueDropDown_2.Items = {'PCA', 'Wavelets', 'Select One'};
            app.FeatureExtractionTechniqueDropDown_2.ValueChangedFcn = createCallbackFcn(app, @FeatureExtractionTechniqueDropDown_2ValueChanged, true);
            app.FeatureExtractionTechniqueDropDown_2.Value = 'Select One';
            app.PrincipalFeaturesSpinner_2Label = uilabel(app.SpikesTab);
            app.PrincipalFeaturesSpinner_2Label.Text = 'Principal Features';
            app.PrincipalFeaturesSpinner_2 = uispinner(app.SpikesTab);
            app.PrincipalFeaturesSpinner_2.Limits = [0 100];
            app.PrincipalFeaturesSpinner_2.ValueDisplayFormat = '%.0f';
            app.PrincipalFeaturesSpinner_2.Value = 10;
            app.FeaturesTab = uitab(app.TabGroup);
            app.FeaturesTab.Title = 'Features';
            app.LoadFeaturesButton = uibutton(app.FeaturesTab, 'push');
            app.LoadFeaturesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFeaturesButtonPushed, true);
            app.LoadFeaturesButton.Text = 'Load Features';
            app.LoadGroundTruthButton_2 = uibutton(app.FeaturesTab, 'push');
            app.LoadGroundTruthButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadGroundTruthButton_2Pushed, true);
            app.LoadGroundTruthButton_2.Text = 'Load Ground Truth';
            app.UIAxes2 = uiaxes(app.ClusteringUIFigure);
            title(app.UIAxes2, 'Clustered Spikes')
            xlabel(app.UIAxes2, 'Time  ')
            ylabel(app.UIAxes2, 'Amplitude ')
            app.UIAxes = uiaxes(app.ClusteringUIFigure);
            title(app.UIAxes, 'Clustered  Features')
            xlabel(app.UIAxes, 'Dim 1')
            ylabel(app.UIAxes, 'Dim 2')
            app.SlidetogetNgroupsofdataLabel = uilabel(app.ClusteringUIFigure);
            app.SlidetogetNgroupsofdataLabel.Text = {'Slide to get ''N'''; 'groups of data'};
            app.SlidetogetNgroupsofdataSlider = uislider(app.ClusteringUIFigure);
            app.SlidetogetNgroupsofdataSlider.Limits = [1 30];
            app.SlidetogetNgroupsofdataSlider.MinorTicks = [];
            app.SlidetogetNgroupsofdataSlider.Value = 7;
            app.UIAxes3 = uiaxes(app.ClusteringUIFigure);
            title(app.UIAxes3, '`')
            xlabel(app.UIAxes3, 'Samples')
            ylabel(app.UIAxes3, 'Amplitude')
            app.ClusteringAccuracyLabel = uilabel(app.ClusteringUIFigure);
            app.ClusteringAccuracyLabel.Text = {'Clustering'; 'Accuracy (%)'};
            app.ClusteringAccuracyGauge = uigauge(app.ClusteringUIFigure, 'circular');
        end
    end

    methods (Access = public)

        % Construct app
        function app = largeDatahandle

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ClusteringUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ClusteringUIFigure)
        end
    end
end